classdef Harmonograph < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        GridLayout3             matlab.ui.container.GridLayout
        RandomizeButton         matlab.ui.control.Button
        Version10NirAlmogLabel  matlab.ui.control.Label
        SaveFigureButton        matlab.ui.control.Button
        HarmonographLabel       matlab.ui.control.Label
        GenerateButton          matlab.ui.control.Button
        CreateyourownMathematicalArtLabel  matlab.ui.control.Label
        DecayFactorsLabel       matlab.ui.control.Label
        PhasesxLabel            matlab.ui.control.Label
        FrequenciesLabel        matlab.ui.control.Label
        AmplitudesLabel         matlab.ui.control.Label
        DecayGridLayout         matlab.ui.container.GridLayout
        Decay4Spinner           matlab.ui.control.Spinner
        Decay3Spinner           matlab.ui.control.Spinner
        Decay2Spinner           matlab.ui.control.Spinner
        Decay1Spinner           matlab.ui.control.Spinner
        PhaseGridLayout         matlab.ui.container.GridLayout
        Phase4Spinner           matlab.ui.control.Spinner
        Phase3Spinner           matlab.ui.control.Spinner
        Phase2Spinner           matlab.ui.control.Spinner
        Phase1Spinner           matlab.ui.control.Spinner
        FrequencyGridLayout     matlab.ui.container.GridLayout
        Frequency4Spinner       matlab.ui.control.Spinner
        Frequency3Spinner       matlab.ui.control.Spinner
        Frequency2Spinner       matlab.ui.control.Spinner
        Frequency1Spinner       matlab.ui.control.Spinner
        AmplitudeGridLayout     matlab.ui.container.GridLayout
        Amplitude4Spinner       matlab.ui.control.Spinner
        Amplitude3Spinner       matlab.ui.control.Spinner
        Amplitude1Spinner       matlab.ui.control.Spinner
        Amplitude2Spinner       matlab.ui.control.Spinner
        RightPanel              matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            % Harmonograph Parameters - Amplitude, Frequency, Phase, and Decay matrices
            p.amplitudes = [app.Amplitude1Spinner.Value, app.Amplitude2Spinner.Value;      % [Amplitude of 1st pendulum, Amplitude of 1st decay sinus]
                            app.Amplitude3Spinner.Value, app.Amplitude4Spinner.Value];     % [Amplitude of 2nd pendulum, Amplitude of 2nd decay sinus]
            
            p.frequencies = [app.Frequency1Spinner.Value, app.Frequency2Spinner.Value;     % [Frequency of 1st pendulum, Frequency of 1st decay sinus]
                             app.Frequency3Spinner.Value, app.Frequency4Spinner.Value];    % [Frequency of 2nd pendulum, Frequency of 2nd decay sinus]
            
            p.phases = pi./[app.Phase1Spinner.Value,  app.Phase2Spinner.Value;         % [Phase angle of 1st pendulum, Phase angle of 1st decay sinus]
                                app.Phase3Spinner.Value,  app.Phase4Spinner.Value];        % [Phase angle of 2nd pendulum, Phase angle of 2nd decay sinus]
            
            p.decays = 0.005.*[app.Decay1Spinner.Value, app.Decay2Spinner.Value;    % [Exponential decay of 1st pendulum, Exponential decay of 1st decay sinus]
                               app.Decay3Spinner.Value, app.Decay4Spinner.Value];    % [Exponential decay of 2nd pendulum, Exponential decay of 2nd decay sinus]
            
            p.time_limit = 30;
            
            points = HarmonographV2func(p);
            
            % Plot the Harmonograph
            darkBackground = rand(1, 3) * 0.2;
            brightColor = rand(1, 3) * 0.6 + 0.4;
            set(app.UIAxes, 'Color', darkBackground);
            plot(app.UIAxes ,points(1,:), points(2,:), 'Color', brightColor, 'LineWidth', 1.0);
            axis(app.UIAxes, 'equal');
            app.UIAxes.XTickLabel = [];
            app.UIAxes.YTickLabel = [];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.DataAspectRatio = [1, 1, 1];
        end

        % Button pushed function: SaveFigureButton
        function SaveFigureButtonPushed(app, event)
            Note = ' | Amplitude 1  | ' + string(app.Amplitude1Spinner.Value) + ...
                   ' | Amplitude 2  | ' + string(app.Amplitude2Spinner.Value) + ...
                   ' | Amplitude 3  | ' + string(app.Amplitude3Spinner.Value) + ...
                   ' | Amplitude 4  | ' + string(app.Amplitude4Spinner.Value) + ...
                   ' | Frequency 1  | ' + string(app.Frequency1Spinner.Value) + ...
                   ' | Frequency 2  | ' + string(app.Frequency2Spinner.Value) + ...
                   ' | Frequency 3  | ' + string(app.Frequency3Spinner.Value) + ...
                   ' | Frequency 4  | ' + string(app.Frequency4Spinner.Value) + ...
                   ' | Phase 1  | ' + string(pi/app.Phase1Spinner.Value) + ...
                   ' | Phase 2  | ' + string(pi/app.Phase2Spinner.Value) + ...
                   ' | Phase 3  | ' + string(pi/app.Phase3Spinner.Value) + ...
                   ' | Phase 4  | ' + string(pi/app.Phase4Spinner.Value) + ...
                   ' | Decay Factor 1  | ' + string(0.005*app.Decay1Spinner.Value) + ...
                   ' | Decay Factor 2  | ' + string(0.005*app.Decay2Spinner.Value) + ...
                   ' | Decay Factor 3  | ' + string(0.005*app.Decay3Spinner.Value) + ...
                   ' | Decay Factor 4  | ' + string(0.005*app.Decay4Spinner.Value);

            % Get the current date and time
            currentTime = clock;
            dateStr = datestr(currentTime, 'yyyymmdd_HHMMSS');
        
            % Save the current figure displayed in the UI Axis as a PNG image with the date and time as the file name
            fileName = [dateStr, '_figure.png'];
            exportgraphics(app.UIAxes, fileName, 'Resolution', 300);
        
            % Save a note file with the same name (date and time) and some text content
            noteFileName = [dateStr, '_note.txt'];
            noteContent = Note;
            fid = fopen(noteFileName, 'wt');
            fprintf(fid, '%s\n\r', noteContent);
            fclose(fid);
        end

        % Button pushed function: RandomizeButton
        function RandomizeButtonPushed(app, event)
            app.Amplitude1Spinner.Value = randi([0,9]);
            app.Amplitude2Spinner.Value = randi([0,9]);
            app.Amplitude3Spinner.Value = randi([0,9]);
            app.Amplitude4Spinner.Value = randi([0,9]);
            
            app.Frequency1Spinner.Value = randi([0,9]);
            app.Frequency2Spinner.Value = randi([0,9]);
            app.Frequency3Spinner.Value = randi([0,9]);
            app.Frequency4Spinner.Value = randi([0,9]);
            
            app.Phase1Spinner.Value = randi([1,9]);
            app.Phase2Spinner.Value = randi([1,9]);
            app.Phase3Spinner.Value = randi([1,9]);
            app.Phase4Spinner.Value = randi([1,9]);
            
            app.Decay1Spinner.Value = randi([0,9]);
            app.Decay2Spinner.Value = randi([0,9]);
            app.Decay3Spinner.Value = randi([0,9]);
            app.Decay4Spinner.Value = randi([0,9]);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {391, 391};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {277, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.AmplitudeGridLayout = uigridlayout(app.GridLayout2);
            app.Amplitude2Spinner = uispinner(app.AmplitudeGridLayout);
            app.Amplitude2Spinner.Limits = [0 9];
            app.Amplitude2Spinner.Editable = 'off';
            app.Amplitude2Spinner.Value = 5;
            app.Amplitude1Spinner = uispinner(app.AmplitudeGridLayout);
            app.Amplitude1Spinner.Limits = [0 9];
            app.Amplitude1Spinner.Editable = 'off';
            app.Amplitude1Spinner.Value = 1;
            app.Amplitude3Spinner = uispinner(app.AmplitudeGridLayout);
            app.Amplitude3Spinner.Limits = [0 9];
            app.Amplitude3Spinner.Editable = 'off';
            app.Amplitude3Spinner.Value = 5;
            app.Amplitude4Spinner = uispinner(app.AmplitudeGridLayout);
            app.Amplitude4Spinner.Limits = [0 9];
            app.Amplitude4Spinner.Editable = 'off';
            app.Amplitude4Spinner.Value = 1;
            app.FrequencyGridLayout = uigridlayout(app.GridLayout2);
            app.Frequency1Spinner = uispinner(app.FrequencyGridLayout);
            app.Frequency1Spinner.Limits = [0 9];
            app.Frequency1Spinner.Editable = 'off';
            app.Frequency1Spinner.Value = 7;
            app.Frequency2Spinner = uispinner(app.FrequencyGridLayout);
            app.Frequency2Spinner.Limits = [0 9];
            app.Frequency2Spinner.Editable = 'off';
            app.Frequency2Spinner.Value = 1;
            app.Frequency3Spinner = uispinner(app.FrequencyGridLayout);
            app.Frequency3Spinner.Limits = [0 9];
            app.Frequency3Spinner.Editable = 'off';
            app.Frequency3Spinner.Value = 1;
            app.Frequency4Spinner = uispinner(app.FrequencyGridLayout);
            app.Frequency4Spinner.Limits = [0 9];
            app.Frequency4Spinner.Editable = 'off';
            app.Frequency4Spinner.Value = 7;
            app.PhaseGridLayout = uigridlayout(app.GridLayout2);
            app.Phase1Spinner = uispinner(app.PhaseGridLayout);
            app.Phase1Spinner.Limits = [1 9];
            app.Phase1Spinner.Editable = 'off';
            app.Phase1Spinner.Value = 2;
            app.Phase2Spinner = uispinner(app.PhaseGridLayout);
            app.Phase2Spinner.Limits = [1 9];
            app.Phase2Spinner.Editable = 'off';
            app.Phase2Spinner.Value = 3;
            app.Phase3Spinner = uispinner(app.PhaseGridLayout);
            app.Phase3Spinner.Limits = [1 9];
            app.Phase3Spinner.Editable = 'off';
            app.Phase3Spinner.Value = 1;
            app.Phase4Spinner = uispinner(app.PhaseGridLayout);
            app.Phase4Spinner.Limits = [1 9];
            app.Phase4Spinner.Editable = 'off';
            app.Phase4Spinner.Value = 6;
            app.DecayGridLayout = uigridlayout(app.GridLayout2);
            app.Decay1Spinner = uispinner(app.DecayGridLayout);
            app.Decay1Spinner.Limits = [0 9];
            app.Decay1Spinner.Editable = 'off';
            app.Decay1Spinner.Value = 6;
            app.Decay2Spinner = uispinner(app.DecayGridLayout);
            app.Decay2Spinner.Limits = [0 9];
            app.Decay2Spinner.Editable = 'off';
            app.Decay2Spinner.Value = 6;
            app.Decay3Spinner = uispinner(app.DecayGridLayout);
            app.Decay3Spinner.Limits = [0 9];
            app.Decay3Spinner.Editable = 'off';
            app.Decay3Spinner.Value = 2;
            app.Decay4Spinner = uispinner(app.DecayGridLayout);
            app.Decay4Spinner.Limits = [0 9];
            app.Decay4Spinner.Editable = 'off';
            app.Decay4Spinner.Value = 6;
            app.AmplitudesLabel = uilabel(app.GridLayout2);
            app.AmplitudesLabel.Text = 'Amplitudes';
            app.FrequenciesLabel = uilabel(app.GridLayout2);
            app.FrequenciesLabel.Text = 'Frequencies';
            app.PhasesxLabel = uilabel(app.GridLayout2);
            app.PhasesxLabel.Text = 'Phases (π/x)';
            app.DecayFactorsLabel = uilabel(app.GridLayout2);
            app.DecayFactorsLabel.Text = 'Decay Factors';
            app.CreateyourownMathematicalArtLabel = uilabel(app.GridLayout2);
            app.CreateyourownMathematicalArtLabel.Text = 'Create your own Mathematical Art!';
            app.GenerateButton = uibutton(app.GridLayout2, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.HarmonographLabel = uilabel(app.GridLayout2);
            app.HarmonographLabel.Text = 'Harmonograph';
            app.SaveFigureButton = uibutton(app.GridLayout2, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigureButton.Text = 'Save Figure';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.Version10NirAlmogLabel = uilabel(app.GridLayout3);
            app.Version10NirAlmogLabel.Text = {'Version 1.0'; 'Nir Almog'};
            app.RandomizeButton = uibutton(app.GridLayout3, 'push');
            app.RandomizeButton.ButtonPushedFcn = createCallbackFcn(app, @RandomizeButtonPushed, true);
            app.RandomizeButton.Text = 'Randomize';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Harmonograph

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end